classdef tree_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatisticsPanel                 matlab.ui.container.Panel
        NumberofbranchesCounter         matlab.ui.control.Label
        NumberofbranchesLabel           matlab.ui.control.Label
        NumberofleavesLabel             matlab.ui.control.Label
        NumberofleavesCounter           matlab.ui.control.Label
        SavestlfileofleafButton         matlab.ui.control.Button
        PresetsPanel                    matlab.ui.container.Panel
        PineButton                      matlab.ui.control.Button
        BaobabButton                    matlab.ui.control.Button
        DragonsbloodButton              matlab.ui.control.Button
        WeepingwillowButton             matlab.ui.control.Button
        PurpleleafplumButton            matlab.ui.control.Button
        LeavespresentDropDownLabel_3    matlab.ui.control.Label
        SeasonDropdown                  matlab.ui.control.DropDown
        BeechButton                     matlab.ui.control.Button
        LeavesPanel                     matlab.ui.container.Panel
        LeavespresentDropDown           matlab.ui.control.DropDown
        LeavespresentDropDownLabel      matlab.ui.control.Label
        LeavespresentDropDownLabel_2    matlab.ui.control.Label
        ColourofleavesDropDown          matlab.ui.control.DropDown
        NumberofleafverticesSlider      matlab.ui.control.Slider
        NumberofleafverticesSliderLabel  matlab.ui.control.Label
        SizeofleavesSlider              matlab.ui.control.Slider
        SizeofleavesSliderLabel         matlab.ui.control.Label
        GravityandwindPanel             matlab.ui.container.Panel
        Gravitationalconstantms2Slider  matlab.ui.control.Slider
        Gravitationalconstantms2SliderLabel  matlab.ui.control.Label
        Windspeedms1Slider              matlab.ui.control.Slider
        Windspeedms1SliderLabel         matlab.ui.control.Label
        SubsequentbranchesPanel         matlab.ui.container.Panel
        TaperingofbranchesSlider        matlab.ui.control.Slider
        TaperingofbranchesSliderLabel   matlab.ui.control.Label
        ChildrenateachsplitSlider       matlab.ui.control.Slider
        ChildrenateachsplitSliderLabel  matlab.ui.control.Label
        LengthofsegmentSlider           matlab.ui.control.Slider
        LengthofsegmentSliderLabel      matlab.ui.control.Label
        MaxrotationatsplitSlider        matlab.ui.control.Slider
        MaxrotationatsplitSliderLabel   matlab.ui.control.Label
        AngleateachsplitSlider          matlab.ui.control.Slider
        AngleateachsplitSliderLabel     matlab.ui.control.Label
        NumberofgenerationsSlider       matlab.ui.control.Slider
        NumberofgenerationsSliderLabel  matlab.ui.control.Label
        FirstbranchesPanel              matlab.ui.container.Panel
        VerticaltaperSlider             matlab.ui.control.Slider
        VerticalbiasoftopbranchSliderLabel_2  matlab.ui.control.Label
        VerticalbiasoftopbranchSlider   matlab.ui.control.Slider
        VerticalbiasoftopbranchSliderLabel  matlab.ui.control.Label
        LengthofbranchSlider            matlab.ui.control.Slider
        LengthofbranchSliderLabel       matlab.ui.control.Label
        AnglefromtrunkdegSlider         matlab.ui.control.Slider
        AnglefromtrunkdegSliderLabel    matlab.ui.control.Label
        TrunkPanel                      matlab.ui.container.Panel
        TrunksegmentsSlider             matlab.ui.control.Slider
        TrunksegmentsSliderLabel        matlab.ui.control.Label
        TrunkheightmSlider              matlab.ui.control.Slider
        TrunkheightmSliderLabel         matlab.ui.control.Label
        LeafbaseheightmSlider           matlab.ui.control.Slider
        LeafbaseheightmSliderLabel      matlab.ui.control.Label
        TrunkdiametermSlider            matlab.ui.control.Slider
        TrunkdiametermSliderLabel       matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Trunk
        h_leafbase=1.8; %Height of leaf base
        d_trunk=0.5; %Trunk diameter
        h_trunk=10; %Trunk height
        n_trunk_seg=6; %Trunk segments
        
        %First generation branches
        ang_firstbranch=40; %First branch angle from trunk
        l_firstbranch=1.5; %First branch length
        fac_crown_vertical=3; %Verticality of first branch crown (higher=more vertical)
        fac_verticaltaper=0; %Taper with height
        
        %Later generation branches
        n_branch_generations=6; %Number of generations
        ang_branch_split=30; %branch angles at split
        ang_branch_rot=360; %max rotation at split
        l_branch_segment=1.5; %Length of each segment
        n_branch_children=2; %Children at each split
        fac_branch_taper=1; %Tapering factor of branches with each rank
        
        %Gravity and wind
        const_gravity=9.81; %Gravity constant
        speed_wind=0; %Wind speed
        
        %Leaves
        bool_leaf="Yes"; %Prescence of leaves (Present, Not present, Fallen)
        colour_leaf="Deep green"; %Colour of leaves (Brown, purple, deep green, light green, muted green)
        size_leaf=1; %Size of leaves (0.5-1.5)
        n_leaf_vertices=5; %Leaf vertices
        
        %Statistics
        number_leaves; % Description
        number_branches; % Description
    end
    
    methods (Access = private)

        function results = Update(app)
            cla(app.UIAxes); % Clear axes
            hold(app.UIAxes,'on') % Hold all plots on axes
            % Run tree.m with inputs from properties
            [app.number_leaves,app.number_branches]=tree(app.UIAxes,app.h_leafbase,app.d_trunk,app.h_trunk,app.n_trunk_seg,app.ang_firstbranch,app.l_firstbranch,app.fac_crown_vertical,app.fac_verticaltaper,app.n_branch_generations,app.ang_branch_split,app.ang_branch_rot,app.l_branch_segment,app.n_branch_children,app.fac_branch_taper,app.const_gravity,app.speed_wind,app.bool_leaf,app.colour_leaf,app.size_leaf,app.n_leaf_vertices)
            app.UIAxes.View=[45,20] % Set view
            app.NumberofleavesCounter.Text=string(app.number_leaves) % Display number of leaves on counter
            app.NumberofbranchesCounter.Text=string(app.number_branches) % Display number of branches on counter
            axis(app.UIAxes,'off') % Hide axes
            i=0
            % Rotate view about z axis and oscillate up and down (while
            % figure is displayed)
            while isvalid(app.UIAxes)
                try
                    pause(0.5)
                    i=i+1
                    app.UIAxes.View=[45+2*i,5+20*sind(i)]
                catch
                    break;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Update % Update tree
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.Update % Update tree
        end

        % Value changed function: LeafbaseheightmSlider
        function LeafbaseheightmSliderValueChanged(app, event)
            app.h_leafbase = app.LeafbaseheightmSlider.Value; 
        end

        % Value changed function: TrunkdiametermSlider
        function TrunkdiametermSliderValueChanged(app, event)
            app.d_trunk = app.TrunkdiametermSlider.Value;
        end

        % Value changed function: TrunkheightmSlider
        function TrunkheightmSliderValueChanged(app, event)
            app.h_trunk = app.TrunkheightmSlider.Value;
        end

        % Value changed function: TrunksegmentsSlider
        function TrunksegmentsSliderValueChanged(app, event)
            value = app.TrunksegmentsSlider.Value;
            app.TrunksegmentsSlider.Value=round( value / 1 ) * 1; % Round to nearest integer
            app.n_trunk_seg = app.TrunksegmentsSlider.Value; % Set property based on slider value
        end

        % Button pushed function: BeechButton
        function BeechButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=1.8;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=0.5
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=10
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=6
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=40
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=1.5
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=3
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=0
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=6
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=30
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=360
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=1.5
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=2
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=1
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=9.81
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Deep green"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=1
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=5
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Summer"
        end

        % Value changed function: LeavespresentDropDown
        function LeavespresentDropDownValueChanged(app, event)
            app.bool_leaf = app.LeavespresentDropDown.Value;
            app.SeasonDropdown.Value="Choose..." % Reset season selector
        end

        % Value changed function: ColourofleavesDropDown
        function ColourofleavesDropDownValueChanged(app, event)
            app.colour_leaf = app.ColourofleavesDropDown.Value;
            app.SeasonDropdown.Value="Choose..." % Reset season selector
        end

        % Value changed function: NumberofleafverticesSlider
        function NumberofleafverticesSliderValueChanged(app, event)
            value = app.NumberofleafverticesSlider.Value;
            app.NumberofleafverticesSlider.Value=round( value / 1 ) * 1; % Round to nearest integer
            app.n_leaf_vertices = app.NumberofleafverticesSlider.Value; % Set property based on slider value
        end

        % Value changed function: SizeofleavesSlider
        function SizeofleavesSliderValueChanged(app, event)
            app.size_leaf = app.SizeofleavesSlider.Value;
            app.SeasonDropdown.Value="Choose..." % Reset season selector
        end

        % Value changed function: Windspeedms1Slider
        function Windspeedms1SliderValueChanged(app, event)
            app.speed_wind = app.Windspeedms1Slider.Value;
        end

        % Value changed function: Gravitationalconstantms2Slider
        function Gravitationalconstantms2SliderValueChanged(app, event)
            app.const_gravity = app.Gravitationalconstantms2Slider.Value;
        end

        % Value changed function: VerticalbiasoftopbranchSlider
        function VerticalbiasoftopbranchSliderValueChanged(app, event)
            app.fac_crown_vertical = app.VerticalbiasoftopbranchSlider.Value;
        end

        % Value changed function: LengthofbranchSlider
        function LengthofbranchSliderValueChanged(app, event)
            app.l_firstbranch = app.LengthofbranchSlider.Value;
        end

        % Value changed function: AnglefromtrunkdegSlider
        function AnglefromtrunkdegSliderValueChanged(app, event)
            app.ang_firstbranch= app.AnglefromtrunkdegSlider.Value;
        end

        % Value changed function: TaperingofbranchesSlider
        function TaperingofbranchesSliderValueChanged(app, event)
            app.fac_branch_taper = app.TaperingofbranchesSlider.Value;
        end

        % Value changed function: ChildrenateachsplitSlider
        function ChildrenateachsplitSliderValueChanged(app, event)
            value = app.ChildrenateachsplitSlider.Value;
            app.ChildrenateachsplitSlider.Value=round( value / 1 ) * 1; % Round to nearest integer
            app.n_branch_children = app.ChildrenateachsplitSlider.Value; % Set property based on slider value
        end

        % Value changed function: LengthofsegmentSlider
        function LengthofsegmentSliderValueChanged(app, event)
            app.l_branch_segment = app.LengthofsegmentSlider.Value;
        end

        % Value changed function: MaxrotationatsplitSlider
        function MaxrotationatsplitSliderValueChanged(app, event)
            app.ang_branch_rot = app.MaxrotationatsplitSlider.Value;
        end

        % Value changed function: AngleateachsplitSlider
        function AngleateachsplitSliderValueChanged(app, event)
            app.ang_branch_split = app.AngleateachsplitSlider.Value;
        end

        % Value changed function: NumberofgenerationsSlider
        function NumberofgenerationsSliderValueChanged(app, event)
            value = app.NumberofgenerationsSlider.Value;
            app.NumberofgenerationsSlider.Value=round( value / 1 ) * 1; % Round to nearest integer
            app.n_branch_generations = app.NumberofgenerationsSlider.Value; % Set property based on slider value
        end

        % Value changed function: SeasonDropdown
        function SeasonDropdownValueChanged(app, event)
            season = app.SeasonDropdown.Value; % Set season property to value of slider
            if season=="Spring" % set sliders and properties according to spring preset
                app.LeavespresentDropDown.Value='Yes'
                app.bool_leaf=app.LeavespresentDropDown.Value
                app.SizeofleavesSlider.Value=0.6
                app.size_leaf=app.SizeofleavesSlider.Value
                app.ColourofleavesDropDown.Value="Light green"
                app.colour_leaf=app.ColourofleavesDropDown.Value
            end
            if season=="Summer" % set sliders and properties according to summer preset
                app.LeavespresentDropDown.Value='Yes'
                app.bool_leaf=app.LeavespresentDropDown.Value
                app.SizeofleavesSlider.Value=1
                app.size_leaf=app.SizeofleavesSlider.Value
                app.ColourofleavesDropDown.Value="Deep green"
                app.colour_leaf=app.ColourofleavesDropDown.Value
            end
            if season=="Early Autumn" % set sliders and properties according to early autumn preset
                app.LeavespresentDropDown.Value='Yes'
                app.bool_leaf=app.LeavespresentDropDown.Value
                app.SizeofleavesSlider.Value=1
                app.size_leaf=app.SizeofleavesSlider.Value
                app.ColourofleavesDropDown.Value="Brown"
                app.colour_leaf=app.ColourofleavesDropDown.Value
            end
            if season=="Late Autumn" % set sliders and properties according to late autumn preset
                app.LeavespresentDropDown.Value='Fallen'
                app.bool_leaf=app.LeavespresentDropDown.Value
                app.SizeofleavesSlider.Value=1
                app.size_leaf=app.SizeofleavesSlider.Value
                app.ColourofleavesDropDown.Value="Brown"
                app.colour_leaf=app.ColourofleavesDropDown.Value
            end
            if season=="Winter" % set sliders and properties according to winter preset
                app.LeavespresentDropDown.Value='No'
                app.bool_leaf=app.LeavespresentDropDown.Value
            end
        end

        % Button pushed function: PurpleleafplumButton
        function PurpleleafplumButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=3.2;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=0.5
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=11
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=7
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=18
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=1.5
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=3
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=0
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=5
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=40
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=360
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=1.1
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=3
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=2
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=9.81
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Purple"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=1
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=5
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Choose..."
        end

        % Button pushed function: WeepingwillowButton
        function WeepingwillowButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=5;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=0.5
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=13
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=9
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=38
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=3.5
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=1
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=0
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=6
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=20
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=360
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=1
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=2
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=1.2
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=20
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Light green"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=0.6
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=5
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Choose..."
        end

        % Button pushed function: DragonsbloodButton
        function DragonsbloodButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=5;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=1.1
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=6
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=10
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=40
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=0.8
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=3
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=0
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=6
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=30
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=200
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=2
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=2
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=1.4
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=6
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Muted green"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=0.7
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=3
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Choose..."
        end

        % Button pushed function: BaobabButton
        function BaobabButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=5;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=1.3
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=6
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=4
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=78
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=3.2
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=1
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=0
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=6
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=54
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=240
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=0.5
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=3
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=2
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=6
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Deep green"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=0.5
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=5
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Choose..."
        end

        % Value changed function: VerticaltaperSlider
        function VerticaltaperSliderValueChanged(app, event)
            app.fac_verticaltaper = app.VerticaltaperSlider.Value;
        end

        % Button pushed function: PineButton
        function PineButtonButtonPushed(app, event)
            % Move all sliders and set all property values
            app.LeafbaseheightmSlider.Value=0.1;
            app.LeafbaseheightmSliderValueChanged
            app.TrunkdiametermSlider.Value=0.5
            app.TrunkdiametermSliderValueChanged
            app.TrunkheightmSlider.Value=16
            app.TrunkheightmSliderValueChanged
            app.TrunksegmentsSlider.Value=25
            app.TrunksegmentsSliderValueChanged
            app.AnglefromtrunkdegSlider.Value=80
            app.AnglefromtrunkdegSliderValueChanged
            app.LengthofbranchSlider.Value=2.1
            app.LengthofbranchSliderValueChanged
            app.VerticalbiasoftopbranchSlider.Value=3
            app.VerticalbiasoftopbranchSliderValueChanged
            app.VerticaltaperSlider.Value=1.4
            app.VerticaltaperSliderValueChanged
            app.NumberofgenerationsSlider.Value=6
            app.NumberofgenerationsSliderValueChanged
            app.AngleateachsplitSlider.Value=36
            app.AngleateachsplitSliderValueChanged
            app.MaxrotationatsplitSlider.Value=100
            app.MaxrotationatsplitSliderValueChanged
            app.LengthofsegmentSlider.Value=1.2
            app.LengthofsegmentSliderValueChanged
            app.ChildrenateachsplitSlider.Value=2
            app.ChildrenateachsplitSliderValueChanged
            app.TaperingofbranchesSlider.Value=1.5
            app.TaperingofbranchesSliderValueChanged
            app.Gravitationalconstantms2Slider.Value=14
            app.Gravitationalconstantms2SliderValueChanged
            app.Windspeedms1Slider.Value=0
            app.Windspeedms1SliderValueChanged
            app.LeavespresentDropDown.Value='Yes'
            app.LeavespresentDropDownValueChanged
            app.ColourofleavesDropDown.Value="Muted green"
            app.ColourofleavesDropDownValueChanged
            app.SizeofleavesSlider.Value=1.4
            app.SizeofleavesSliderValueChanged
            app.NumberofleafverticesSlider.Value=3
            app.NumberofleafverticesSliderValueChanged
            app.SeasonDropdown.Value="Choose..."
        end

        % Button pushed function: SavestlfileofleafButton
        function SavestlfileofleafButtonPushed(app, event)
            % Save stl in parent folder
            leafstl(app.n_leaf_vertices)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'background.jpg';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TrunkPanel = uipanel(app.UIFigure);
            app.TrunkPanel.Title = 'Trunk';
            app.TrunkdiametermSliderLabel = uilabel(app.TrunkPanel);
            app.TrunkdiametermSliderLabel.Text = 'Trunk diameter (m)';
            app.TrunkdiametermSlider = uislider(app.TrunkPanel);
            app.TrunkdiametermSlider.Limits = [0 2];
            app.TrunkdiametermSlider.ValueChangedFcn = createCallbackFcn(app, @TrunkdiametermSliderValueChanged, true);
            app.TrunkdiametermSlider.Value = 0.5;
            app.LeafbaseheightmSliderLabel = uilabel(app.TrunkPanel);
            app.LeafbaseheightmSliderLabel.Text = 'Leaf base height (m)';
            app.LeafbaseheightmSlider = uislider(app.TrunkPanel);
            app.LeafbaseheightmSlider.Limits = [0.1 5];
            app.LeafbaseheightmSlider.MajorTicks = [0 1 2 3 4 5];
            app.LeafbaseheightmSlider.ValueChangedFcn = createCallbackFcn(app, @LeafbaseheightmSliderValueChanged, true);
            app.LeafbaseheightmSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.LeafbaseheightmSlider.Value = 1.8;
            app.TrunkheightmSliderLabel = uilabel(app.TrunkPanel);
            app.TrunkheightmSliderLabel.Text = 'Trunk height (m)';
            app.TrunkheightmSlider = uislider(app.TrunkPanel);
            app.TrunkheightmSlider.Limits = [6 50];
            app.TrunkheightmSlider.ValueChangedFcn = createCallbackFcn(app, @TrunkheightmSliderValueChanged, true);
            app.TrunkheightmSlider.Value = 10;
            app.TrunksegmentsSliderLabel = uilabel(app.TrunkPanel);
            app.TrunksegmentsSliderLabel.Text = 'Trunk segments';
            app.TrunksegmentsSlider = uislider(app.TrunkPanel);
            app.TrunksegmentsSlider.Limits = [2 30];
            app.TrunksegmentsSlider.ValueChangedFcn = createCallbackFcn(app, @TrunksegmentsSliderValueChanged, true);
            app.TrunksegmentsSlider.Value = 6;
            app.FirstbranchesPanel = uipanel(app.UIFigure);
            app.FirstbranchesPanel.Title = 'First branches';
            app.AnglefromtrunkdegSliderLabel = uilabel(app.FirstbranchesPanel);
            app.AnglefromtrunkdegSliderLabel.Text = 'Angle from trunk (deg)';
            app.AnglefromtrunkdegSlider = uislider(app.FirstbranchesPanel);
            app.AnglefromtrunkdegSlider.Limits = [0 90];
            app.AnglefromtrunkdegSlider.ValueChangedFcn = createCallbackFcn(app, @AnglefromtrunkdegSliderValueChanged, true);
            app.AnglefromtrunkdegSlider.Value = 40;
            app.LengthofbranchSliderLabel = uilabel(app.FirstbranchesPanel);
            app.LengthofbranchSliderLabel.Text = 'Length of branch';
            app.LengthofbranchSlider = uislider(app.FirstbranchesPanel);
            app.LengthofbranchSlider.Limits = [0.5 5];
            app.LengthofbranchSlider.ValueChangedFcn = createCallbackFcn(app, @LengthofbranchSliderValueChanged, true);
            app.LengthofbranchSlider.Value = 1.5;
            app.VerticalbiasoftopbranchSliderLabel = uilabel(app.FirstbranchesPanel);
            app.VerticalbiasoftopbranchSliderLabel.Text = 'Vertical bias of top branch';
            app.VerticalbiasoftopbranchSlider = uislider(app.FirstbranchesPanel);
            app.VerticalbiasoftopbranchSlider.Limits = [1 3];
            app.VerticalbiasoftopbranchSlider.ValueChangedFcn = createCallbackFcn(app, @VerticalbiasoftopbranchSliderValueChanged, true);
            app.VerticalbiasoftopbranchSlider.Value = 3;
            app.VerticalbiasoftopbranchSliderLabel_2 = uilabel(app.FirstbranchesPanel);
            app.VerticalbiasoftopbranchSliderLabel_2.Text = 'Taper with height';
            app.VerticaltaperSlider = uislider(app.FirstbranchesPanel);
            app.VerticaltaperSlider.Limits = [0 3];
            app.VerticaltaperSlider.ValueChangedFcn = createCallbackFcn(app, @VerticaltaperSliderValueChanged, true);
            app.SubsequentbranchesPanel = uipanel(app.UIFigure);
            app.SubsequentbranchesPanel.Title = 'Subsequent branches';
            app.NumberofgenerationsSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.NumberofgenerationsSliderLabel.Text = 'Number of generations';
            app.NumberofgenerationsSlider = uislider(app.SubsequentbranchesPanel);
            app.NumberofgenerationsSlider.Limits = [1 6];
            app.NumberofgenerationsSlider.MajorTicks = [1 2 3 4 5 6];
            app.NumberofgenerationsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofgenerationsSliderValueChanged, true);
            app.NumberofgenerationsSlider.MinorTicks = [];
            app.NumberofgenerationsSlider.Value = 6;
            app.AngleateachsplitSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.AngleateachsplitSliderLabel.Text = 'Angle at each split';
            app.AngleateachsplitSlider = uislider(app.SubsequentbranchesPanel);
            app.AngleateachsplitSlider.Limits = [0 90];
            app.AngleateachsplitSlider.ValueChangedFcn = createCallbackFcn(app, @AngleateachsplitSliderValueChanged, true);
            app.AngleateachsplitSlider.Value = 30;
            app.MaxrotationatsplitSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.MaxrotationatsplitSliderLabel.Text = 'Max rotation at split';
            app.MaxrotationatsplitSlider = uislider(app.SubsequentbranchesPanel);
            app.MaxrotationatsplitSlider.Limits = [0 360];
            app.MaxrotationatsplitSlider.ValueChangedFcn = createCallbackFcn(app, @MaxrotationatsplitSliderValueChanged, true);
            app.MaxrotationatsplitSlider.Value = 360;
            app.LengthofsegmentSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.LengthofsegmentSliderLabel.Text = 'Length of segment';
            app.LengthofsegmentSlider = uislider(app.SubsequentbranchesPanel);
            app.LengthofsegmentSlider.Limits = [0.5 5];
            app.LengthofsegmentSlider.ValueChangedFcn = createCallbackFcn(app, @LengthofsegmentSliderValueChanged, true);
            app.LengthofsegmentSlider.Value = 1.5;
            app.ChildrenateachsplitSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.ChildrenateachsplitSliderLabel.Text = 'Children at each split';
            app.ChildrenateachsplitSlider = uislider(app.SubsequentbranchesPanel);
            app.ChildrenateachsplitSlider.Limits = [1 3];
            app.ChildrenateachsplitSlider.MajorTicks = [1 2 3];
            app.ChildrenateachsplitSlider.MajorTickLabels = {'1', '2', '3'};
            app.ChildrenateachsplitSlider.ValueChangedFcn = createCallbackFcn(app, @ChildrenateachsplitSliderValueChanged, true);
            app.ChildrenateachsplitSlider.MinorTicks = [];
            app.ChildrenateachsplitSlider.Value = 2;
            app.TaperingofbranchesSliderLabel = uilabel(app.SubsequentbranchesPanel);
            app.TaperingofbranchesSliderLabel.Text = 'Tapering of branches';
            app.TaperingofbranchesSlider = uislider(app.SubsequentbranchesPanel);
            app.TaperingofbranchesSlider.Limits = [0 2];
            app.TaperingofbranchesSlider.ValueChangedFcn = createCallbackFcn(app, @TaperingofbranchesSliderValueChanged, true);
            app.TaperingofbranchesSlider.Value = 1;
            app.GravityandwindPanel = uipanel(app.UIFigure);
            app.GravityandwindPanel.Title = 'Gravity and wind';
            app.Windspeedms1SliderLabel = uilabel(app.GravityandwindPanel);
            app.Windspeedms1SliderLabel.Text = 'Wind speed (ms^-1)';
            app.Windspeedms1Slider = uislider(app.GravityandwindPanel);
            app.Windspeedms1Slider.Limits = [0 40];
            app.Windspeedms1Slider.ValueChangedFcn = createCallbackFcn(app, @Windspeedms1SliderValueChanged, true);
            app.Gravitationalconstantms2SliderLabel = uilabel(app.GravityandwindPanel);
            app.Gravitationalconstantms2SliderLabel.Text = 'Gravitational constant (ms^-2)';
            app.Gravitationalconstantms2Slider = uislider(app.GravityandwindPanel);
            app.Gravitationalconstantms2Slider.Limits = [0 20];
            app.Gravitationalconstantms2Slider.ValueChangedFcn = createCallbackFcn(app, @Gravitationalconstantms2SliderValueChanged, true);
            app.Gravitationalconstantms2Slider.Value = 9.81;
            app.LeavesPanel = uipanel(app.UIFigure);
            app.LeavesPanel.Title = 'Leaves';
            app.SizeofleavesSliderLabel = uilabel(app.LeavesPanel);
            app.SizeofleavesSliderLabel.Text = 'Size of leaves';
            app.SizeofleavesSlider = uislider(app.LeavesPanel);
            app.SizeofleavesSlider.Limits = [0.5 1.5];
            app.SizeofleavesSlider.ValueChangedFcn = createCallbackFcn(app, @SizeofleavesSliderValueChanged, true);
            app.SizeofleavesSlider.Value = 1;
            app.NumberofleafverticesSliderLabel = uilabel(app.LeavesPanel);
            app.NumberofleafverticesSliderLabel.Text = 'Number of leaf vertices';
            app.NumberofleafverticesSlider = uislider(app.LeavesPanel);
            app.NumberofleafverticesSlider.Limits = [3 7];
            app.NumberofleafverticesSlider.MajorTicks = [3 4 5 6 7];
            app.NumberofleafverticesSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofleafverticesSliderValueChanged, true);
            app.NumberofleafverticesSlider.MinorTicks = [];
            app.NumberofleafverticesSlider.Value = 5;
            app.ColourofleavesDropDown = uidropdown(app.LeavesPanel);
            app.ColourofleavesDropDown.Items = {'Light green', 'Deep green', 'Muted green', 'Brown', 'Purple'};
            app.ColourofleavesDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourofleavesDropDownValueChanged, true);
            app.ColourofleavesDropDown.Value = 'Deep green';
            app.LeavespresentDropDownLabel_2 = uilabel(app.LeavesPanel);
            app.LeavespresentDropDownLabel_2.Text = 'Colour of leaves';
            app.LeavespresentDropDownLabel = uilabel(app.LeavesPanel);
            app.LeavespresentDropDownLabel.Text = 'Leaves present?';
            app.LeavespresentDropDown = uidropdown(app.LeavesPanel);
            app.LeavespresentDropDown.Items = {'Yes', 'Fallen', 'No'};
            app.LeavespresentDropDown.ValueChangedFcn = createCallbackFcn(app, @LeavespresentDropDownValueChanged, true);
            app.LeavespresentDropDown.Value = 'Yes';
            app.PresetsPanel = uipanel(app.UIFigure);
            app.PresetsPanel.Title = 'Presets';
            app.BeechButton = uibutton(app.PresetsPanel, 'push');
            app.BeechButton.ButtonPushedFcn = createCallbackFcn(app, @BeechButtonPushed, true);
            app.BeechButton.Text = 'Beech';
            app.SeasonDropdown = uidropdown(app.PresetsPanel);
            app.SeasonDropdown.Items = {'Choose...', 'Spring', 'Summer', 'Early Autumn', 'Late Autumn', 'Winter'};
            app.SeasonDropdown.ValueChangedFcn = createCallbackFcn(app, @SeasonDropdownValueChanged, true);
            app.SeasonDropdown.Value = 'Summer';
            app.LeavespresentDropDownLabel_3 = uilabel(app.PresetsPanel);
            app.LeavespresentDropDownLabel_3.Text = 'Season';
            app.PurpleleafplumButton = uibutton(app.PresetsPanel, 'push');
            app.PurpleleafplumButton.ButtonPushedFcn = createCallbackFcn(app, @PurpleleafplumButtonPushed, true);
            app.PurpleleafplumButton.Text = 'Purple leaf plum';
            app.WeepingwillowButton = uibutton(app.PresetsPanel, 'push');
            app.WeepingwillowButton.ButtonPushedFcn = createCallbackFcn(app, @WeepingwillowButtonPushed, true);
            app.WeepingwillowButton.Text = 'Weeping willow';
            app.DragonsbloodButton = uibutton(app.PresetsPanel, 'push');
            app.DragonsbloodButton.ButtonPushedFcn = createCallbackFcn(app, @DragonsbloodButtonPushed, true);
            app.DragonsbloodButton.Text = 'Dragon''s blood';
            app.BaobabButton = uibutton(app.PresetsPanel, 'push');
            app.BaobabButton.ButtonPushedFcn = createCallbackFcn(app, @BaobabButtonPushed, true);
            app.BaobabButton.Text = 'Baobab';
            app.PineButton = uibutton(app.PresetsPanel, 'push');
            app.PineButton.ButtonPushedFcn = createCallbackFcn(app, @PineButtonButtonPushed, true);
            app.PineButton.Text = 'Pine';
            app.SavestlfileofleafButton = uibutton(app.UIFigure, 'push');
            app.SavestlfileofleafButton.ButtonPushedFcn = createCallbackFcn(app, @SavestlfileofleafButtonPushed, true);
            app.SavestlfileofleafButton.Text = 'Save .stl file of leaf';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.NumberofleavesCounter = uilabel(app.StatisticsPanel);
            app.NumberofleavesCounter.Text = '';
            app.NumberofleavesLabel = uilabel(app.StatisticsPanel);
            app.NumberofleavesLabel.Text = 'Number of leaves:';
            app.NumberofbranchesLabel = uilabel(app.StatisticsPanel);
            app.NumberofbranchesLabel.Text = 'Number of branches:';
            app.NumberofbranchesCounter = uilabel(app.StatisticsPanel);
            app.NumberofbranchesCounter.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tree_generator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end